classdef GoruntuIslemeOdev < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        OriginalAxes                matlab.ui.control.UIAxes
        CenterPanel                 matlab.ui.container.Panel
        ParlaklikSlider             matlab.ui.control.Slider
        ParlaklkLabel               matlab.ui.control.Label
        RenkUzayiDropDown           matlab.ui.control.DropDown
        RenkUzayDropDownLabel       matlab.ui.control.Label
        ScaleFactorEditField        matlab.ui.control.NumericEditField
        lekFaktrLabel               matlab.ui.control.Label
        EndYEditField               matlab.ui.control.NumericEditField
        BitiSutnuLabel              matlab.ui.control.Label
        EndXEditField               matlab.ui.control.NumericEditField
        BitiSatrLabel               matlab.ui.control.Label
        StartYEditField             matlab.ui.control.NumericEditField
        BalangSutnuLabel            matlab.ui.control.Label
        StartXEditField             matlab.ui.control.NumericEditField
        BalangSatrLabel             matlab.ui.control.Label
        KaDereceDndrlecekEditField  matlab.ui.control.NumericEditField
        KaDereceDndrlecekEditField_2Label  matlab.ui.control.Label
        GrntlemeGUILabel            matlab.ui.control.Label
        YaplacaklemDropDown         matlab.ui.control.DropDown
        YaplacaklemDropDownLabel    matlab.ui.control.Label
        Button                      matlab.ui.control.StateButton
        GrntYkleButton              matlab.ui.control.Button
        RightPanel                  matlab.ui.container.Panel
        ProcessedImage              matlab.ui.control.Image
        ProcessedAxes               matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = public)
        LoadedImage % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Button
        function ButtonValueChanged(app, event)
                selectedProcess = strtrim(app.YaplacaklemDropDown.Value);
            
            % Eğer resim yüklenmemişse uyarı ver
            if isempty(app.LoadedImage)
                uialert(app.UIFigure, 'Lütfen önce bir resim yükleyin!', 'Hata');
                return;
            end
            
            % Orijinal görüntüyü al
            img = app.LoadedImage;
            
            
            % Seçilen işleme göre uygun fonksiyonu çalıştır
            switch selectedProcess
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                case 'Gri Dönüşüm'
                     % Boyutları al ve çıktı matrisini oluştur
                       [m, n, ~] = size(img);
                        grayImage = zeros(m, n, 'like', img);
        
       % Her piksel için luminosity yöntemi
        for i = 1:m
            for j = 1:n
                % R, G, B bileşenlerini double olarak oku
                r = double(img(i,j,1));
                g = double(img(i,j,2));
                b = double(img(i,j,3));
                
                % Ağırlıklı ortalama ile gri değerini hesapla
                grayVal = 0.299*r + 0.587*g + 0.114*b;
                
                % Orijinal veri tipine çevirip at
                grayImage(i,j) = cast(grayVal, class(img));
            end
        end
                processedImg = grayImage;
                rgbOut = repmat(processedImg, [1 1 3]);   % m×n×3

    % UIImage’a ata
                app.ProcessedImage.Visible     = 'on';
                app.ProcessedImage.ImageSource = rgbOut;
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                case 'Binary Dönüşüm'

                   [m, n, ~] = size(img);
    grayImage   = zeros(m, n, 'like', img);
    imgBinary   = zeros(m, n, 'uint8');

    % 1) Luminosity yöntemi ile grileştir
    for i = 1:m
        for j = 1:n
            r = double(img(i,j,1));
            g = double(img(i,j,2));
            b = double(img(i,j,3));
            grayVal = 0.299*r + 0.587*g + 0.114*b;
            grayImage(i,j) = cast(grayVal, class(img));
        end
    end

    % 2) Eşik değeri uygula
    threshold = 128;
    mask = grayImage >= threshold;

    % 3) Maskeyi 0/255 değerine çevir
    imgBinary(mask)  = 255;
    imgBinary(~mask) =   0;

    % İşlenmiş resmi ata
    processedImg = imgBinary;
    rgbOut = repmat(processedImg, [1 1 3]);
    app.ProcessedImage.Visible     = 'on';
    app.ProcessedImage.ImageSource = rgbOut;

    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                case 'Görüntü Döndürme'
    % 1) EditField’ten dereceyi sayısal olarak al
    derece = app.KaDereceDndrlecekEditField.Value;  

    % 2) Orijinal resmi al ve boyutları al
    img = app.LoadedImage;
    [m, n, c] = size(img);

    % 3) Döndürülmüş resmi aynı tipte ve boyutta baştan ayır
    rotated = zeros(m, n, c, 'like', img);

    % 4) Döndürme açısını radyana çevir
    theta = deg2rad(derece);

    % 5) Resmin merkezini hesapla
    cx = (m + 1)/2;
    cy = (n + 1)/2;

    % 6) Tersine haritalama (inverse mapping)
    for i = 1:m
        for j = 1:n
            x0 =  cos(theta)*(i - cx) + sin(theta)*(j - cy) + cx;
            y0 = -sin(theta)*(i - cx) + cos(theta)*(j - cy) + cy;
            xi = round(x0);
            yj = round(y0);
            if xi >= 1 && xi <= m && yj >= 1 && yj <= n
                rotated(i, j, :) = img(xi, yj, :);
            end
        end
    end

    % 7) Oluşan resmi sonuç değişkenine ata
    processedImg = rotated;

    % ——————————————
    % 8) Sağ eksendeki axes’i gizleyip UIImage’da göster
    app.ProcessedAxes.Visible    = 'off';
    app.ProcessedImage.Visible   = 'on';
    app.ProcessedImage.ImageSource = processedImg;
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                case 'Görüntü Kırpma'
    % 1) Orijinal resmi al
    img = app.LoadedImage;

    % 2) Kullanıcıdan değerleri al
    startCol = round(app.StartXEditField.Value);
    endCol   = round(app.EndXEditField.Value);
    startRow = round(app.StartYEditField.Value);
    endRow   = round(app.EndYEditField.Value);

    % 3) Satır/sütun swap ve sınır koruması
    if startCol > endCol, tmp = startCol; startCol = endCol; endCol = tmp; end
    if startRow > endRow, tmp = startRow; startRow = endRow; endRow = tmp; end
    [m, n, ~] = size(img);
    startCol = max(1, min(n, startCol));
    endCol   = max(1, min(n, endCol));
    startRow = max(1, min(m, startRow));
    endRow   = max(1, min(m, endRow));

    % 4) Sol eksende orijinal resmi ve kırpma çerçevesini göster
    cla(app.OriginalAxes);
    imshow(img, 'Parent', app.OriginalAxes);
    hold(app.OriginalAxes, 'on');
    rectPos = [startCol, startRow, endCol-startCol, endRow-startRow];
    rectangle(app.OriginalAxes, 'Position', rectPos, ...
              'EdgeColor','r','LineWidth',1.5);
    hold(app.OriginalAxes, 'off');

    % 5) Kırpma işlemi
    processedImg = img(startRow:endRow, startCol:endCol, :);

    % 6) Sağ tarafta UIImage’da sonucu göster
    app.ProcessedAxes.Visible    = 'off';
    app.ProcessedImage.Visible   = 'on';
    app.ProcessedImage.ImageSource = processedImg;


%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
               
                case'Görüntü Yaklaştırma/Uzaklaştırma'
 s = app.ScaleFactorEditField.Value;
    if isempty(s) || ~isnumeric(s) || s <= 0
        uialert(app.UIFigure, 'Pozitif bir ölçek girin.', 'Hata');
        return;
    end

    % Orijinal görüntüyü al ve nearest-neighbor ile zoom yap
    img = app.LoadedImage;
    [m, n, ch] = size(img);
    new_m = max(1, round(m * s));
    new_n = max(1, round(n * s));
    zoomed = zeros(new_m, new_n, ch, 'like', img);
    for i = 1:new_m
        r0 = floor((i - 1) / s) + 1;  r0 = min(max(r0, 1), m);
        for j = 1:new_n
            c0 = floor((j - 1) / s) + 1;  c0 = min(max(c0, 1), n);
            zoomed(i, j, :) = img(r0, c0, :);
        end
    end

    % İşlenmiş resmi ata
    processedImg = zoomed;

    % UIImage’da göster ve kontrolün boyunu resme göre ayarla
    app.ProcessedImage.Visible = 'on';
    % Yeni resmin piksel boyutunu al
    [hImg, wImg, ~] = size(processedImg);
    % Kontrolün pozisyonunu al ve genişlik/yüksekliği güncelle
    pos = app.ProcessedImage.Position;  % [x y oldWidth oldHeight]
    app.ProcessedImage.Position = [pos(1), pos(2), wImg, hImg];
    % Son olarak kaynağı ata
    app.ProcessedImage.ImageSource = processedImg;

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                case 'Renk Uzayı Dönüşümleri'
               
    % 1) Orijinal resmi yükle ve [0..1] aralığına normalize et
    rgb = double(app.LoadedImage) / 255;  
    [h, w, ~] = size(rgb);
    
    % 2) Hangi renk uzayı seçildi ?
    secimUzay = app.RenkUzayiDropDown.Value;

    if strcmp(secimUzay,'HSV')
        % ------- RGB -> HSV (manuel) -------
        H = zeros(h,w);
        S = zeros(h,w);
        V = zeros(h,w);
        
        for i = 1:h
            for j = 1:w
                r = rgb(i,j,1);
                g = rgb(i,j,2);
                b = rgb(i,j,3);
                mx = max([r g b]);
                mn = min([r g b]);
                C  = mx - mn;        % chroma
                
                % Hue (H)
                if C == 0
                    h_val = 0;
                elseif mx == r
                    h_val = mod((g - b)/C, 6);
                elseif mx == g
                    h_val = ((b - r)/C) + 2;
                else
                    h_val = ((r - g)/C) + 4;
                end
                H(i,j) = h_val / 6;   % [0..1]
                
                % Saturation (S)
                if mx == 0
                    S(i,j) = 0;
                else
                    S(i,j) = C / mx;
                end
                
                % Value (V)
                V(i,j) = mx;
            end
        end
        
        % 0–255 aralığına dönüştür ve uint8 yap
        hsvImg = uint8( cat(3, H, S, V) * 255 );
        processedImg = hsvImg;
        
    elseif strcmp(secimUzay,'YCbCr')
        % ------- RGB -> YCbCr (manuel) -------
        Y  = zeros(h,w);
        Cb = zeros(h,w);
        Cr = zeros(h,w);
        for i = 1:h
            for j = 1:w
                r = rgb(i,j,1)*255;
                g = rgb(i,j,2)*255;
                b = rgb(i,j,3)*255;
                % Formüller
                y  =  0.299*r + 0.587*g + 0.114*b;
                cb = 128 - 0.168736*r - 0.331264*g + 0.5*b;
                cr = 128 + 0.5*r - 0.418688*g - 0.081312*b;
                % Sınırla [0..255]
                Y(i,j)  = min(max(y,  0),255);
                Cb(i,j) = min(max(cb, 0),255);
                Cr(i,j) = min(max(cr, 0),255);
            end
        end
        processedImg = uint8( cat(3, Y, Cb, Cr) );
        
    elseif strcmp(secimUzay,'Lab')
        % ------- RGB -> CIE-Lab (manuel) -------
        % 1) Gamma düzeltmesi (sRGB->linear)
        lin = zeros(size(rgb));
        for i = 1:h
            for j = 1:w
                for k = 1:3
                    v = rgb(i,j,k);
                    if v <= 0.04045
                        lin(i,j,k) = v / 12.92;
                    else
                        lin(i,j,k) = ((v+0.055)/1.055)^2.4;
                    end
                end
            end
        end
        % 2) RGB->XYZ dönüşümü (D65)
        Mmat = [0.4124564 0.3575761 0.1804375;
                0.2126729 0.7151522 0.0721750;
                0.0193339 0.1191920 0.9503041];
        XYZ = zeros(h,w,3);
        for i = 1:h
            for j = 1:w
                rgbv = squeeze(lin(i,j,:));
                xyzv = Mmat * rgbv;
                XYZ(i,j,1) = xyzv(1);
                XYZ(i,j,2) = xyzv(2);
                XYZ(i,j,3) = xyzv(3);
            end
        end
        % 3) Beyaz nokta normalize
        Xn = 0.95047; Yn = 1.00000; Zn = 1.08883;
        xr = XYZ(:,:,1)/Xn; yr = XYZ(:,:,2)/Yn; zr = XYZ(:,:,3)/Zn;
        % f(t) fonksiyonu
        eps=0.008856; k=903.3;
        fx = xr.^(1/3); fy = yr.^(1/3); fz = zr.^(1/3);
        idx = xr<eps; fx(idx) = (k*xr(idx)+16)/116;
        idx = yr<eps; fy(idx) = (k*yr(idx)+16)/116;
        idx = zr<eps; fz(idx) = (k*zr(idx)+16)/116;
        % 4) Lab kanalları
        L = 116*fy - 16;      % 0..100
        a = 500*(fx - fy);    % yaklaşık -128..127
        b = 200*(fy - fz);    % yaklaşık -128..127
        % 5) 0-255 aralığına ölçekle
        Lu = uint8((L/100) * 255);
        au = uint8(((a+128)/255) * 255);
        bu = uint8(((b+128)/255) * 255);
        processedImg = cat(3, Lu, au, bu);
    end

    % 3) Son adım: sonucu UIImage’da göster
    app.ProcessedImage.Visible     = 'on';
    app.ProcessedImage.ImageSource = processedImg;
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                case 'Giriş görüntüsüne ait histogram ve orjinal görüntü histogramını germe/genişletme'
    % 1) Orijinali sol eksende göster
                    imshow(app.LoadedImage,'Parent',app.OriginalAxes);

    % 2) Griye çevir
                    gray = rgb2gray(app.LoadedImage);

    % 3) Germe
                    minI = double(min(gray(:)));
                    maxI = double(max(gray(:)));
                    stretched = uint8( (double(gray)-minI)/(maxI-minI)*255 );

    % 4) Histogram verilerini al
                    bins = 0:256;  % 0–256 arası 256 aralık
                    h1   = histcounts(gray,      bins);  % 1×256
                    h2   = histcounts(stretched, bins);  % 1×256
                    x    = 0:255;                     % 1×256

    % 5) Sağ ekseni temizle ve histogramı çiz
                    cla(app.ProcessedAxes);
                    hold(app.ProcessedAxes,'on');
                    bar(app.ProcessedAxes, x, h1, 'FaceAlpha',0.5, 'DisplayName','Orijinal');
                    bar(app.ProcessedAxes, x, h2, 'FaceAlpha',0.5, 'DisplayName','Genişletilmiş');
                    hold(app.ProcessedAxes,'off');

    % 6) Ekseni biçimlendir
                    title(app.ProcessedAxes,   'Histogram Karşılaştırması');
                    xlabel(app.ProcessedAxes, 'Gri Değer');
                    ylabel(app.ProcessedAxes, 'Piksel Sayısı');
                    legend(app.ProcessedAxes, {'Orijinal','Genişletilmiş'}, 'Location','northeast');

    % 7) Görünürlük ayarı
                    app.ProcessedAxes.Visible  = 'on';
                    app.ProcessedImage.Visible = 'off';
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                case 'Resim ekleme'
                    % 1) İkinci resmi seç ve yükle
                [file2, path2] = uigetfile({'*.jpg;*.png;*.bmp'}, 'İkinci Resmi Seç');
                 if isequal(file2,0)
                    return;  % kullanıcı iptal etti
                 end
                img2 = imread(fullfile(path2, file2));

    % 2) Orijinal ve ikinci resmi ortak boyuta getir
                img1 = app.LoadedImage;
                [m1,n1,c1] = size(img1);
                [m2,n2,c2] = size(img2);
                m = min(m1,m2); 
                n = min(n1,n2);
                c = min(c1,c2);
                img1 = img1(1:m,1:n,1:c);
                img2 = img2(1:m,1:n,1:c);

    % 3) Boş sonuç matrisi oluştur (uint8)
                sonuc = zeros(m, n, c, 'uint8');

    % 4) Piksel başına toplama işlemi
                for i = 1:m
                    for j = 1:n
                        for k = 1:c
                            p1 = double(img1(i,j,k));
                            p2 = double(img2(i,j,k));
                            toplam = p1 + p2;
                % 0–255 aralığında tut
                            if toplam > 255
                                toplam = 255;
                            end
                            sonuc(i,j,k) = uint8(toplam);
                        end
                    end
                end

    % 5) Sonucu processedImg’e ata ve UIImage’da göster
                processedImg = sonuc;
                app.ProcessedAxes.Visible    = 'off';
                app.ProcessedImage.Visible   = 'on';
                app.ProcessedImage.ImageSource = processedImg;
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                case 'Resim Çarpma'
                    [file2, path2] = uigetfile({'*.jpg;*.png;*.bmp'}, 'İkinci Resmi Seç');
   
                        if isequal(file2,0)
                                return;  % kullanıcı iptal etti
                        end
                        img2 = imread(fullfile(path2,file2));

    % 2) Boyutları al, ortak boyuta küçült (min değerlerle)
                    img1 = app.LoadedImage;
                    [m1, n1, c1] = size(img1);
                    [m2, n2, c2] = size(img2);
                    m = min(m1,m2);
                    n = min(n1,n2);
                    c = min(c1,c2);
                    img1 = img1(1:m,1:n,1:c);
                    img2 = img2(1:m,1:n,1:c);

    % 3) Boş sonuç matrisi oluştur (uint8)
                    sonuc = zeros(m, n, c, 'uint8');

    % 4) Piksel başına çarpma (0–255 arası)
                for i = 1:m
                  for j = 1:n
                      for k = 1:c
                          % İki pikseli çarp, ölçekle, sınırla
                          p1 = double(img1(i,j,k));
                         p2 = double(img2(i,j,k));
                         carpim = (p1 * p2) / 255;      % 0–255 aralığına indirgeme
                            if carpim > 255, carpim = 255; end
                            if carpim < 0,   carpim = 0;   end
                            sonuc(i,j,k) = uint8(carpim);
                       end
                   end
                end

    % 5) Sonucu atanıp UIImage’da göster
                processedImg = sonuc;
                app.ProcessedAxes.Visible    = 'off';
                app.ProcessedImage.Visible   = 'on';
                app.ProcessedImage.ImageSource = processedImg;                
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                case 'Konvolüsyon İşlemi'
                   
                case 'Eşikleme İşlemi'
                   
                case 'Kenar Bulma Algoritması'
                    
                case 'Görüntüye Gürültü Ekleme'
                  
                case 'Görüntü Filtre Uygulama'
                    
                case 'Morfolojik İşlemler'
                   

                case 'Parlaklık Arttırma'
            img = app.LoadedImage;
            [m,n,c] = size(img);
            faktor = app.ParlaklikSlider.Value;  % –100…+100

            sonuc = zeros(m,n,c,'uint8');
            for i = 1:m
                for j = 1:n
                    for k = 1:c
                        v = double(img(i,j,k)) + faktor;
                        v = min(max(v,0),255);
                        sonuc(i,j,k) = uint8(v);
                    end
                end
            end

            % Sadece UIImage’da göster
            app.ProcessedAxes.Visible      = 'off';
            app.ProcessedImage.Visible     = 'on';
            app.ProcessedImage.ImageSource = sonuc;
                otherwise
                    uialert(app.UIFigure, 'Seçilen işlem desteklenmiyor.', 'Hata');
                    return;
            
            end
            % İşlenmiş görüntüyü sağdaki eksende göster
        
            
        end

        % Value changed function: YaplacaklemDropDown
        function YaplacaklemDropDownValueChanged(app, event)
            if strcmp(app.YaplacaklemDropDown.Value, 'Görüntü Döndürme')
                app.KaDereceDndrlecekEditField.Visible      = 'on';
                app.KaDereceDndrlecekEditField_2Label.Visible = 'on';
            else
                app.KaDereceDndrlecekEditField.Visible      = 'off';
                app.KaDereceDndrlecekEditField_2Label.Visible = 'off';

               if strcmp(app.YaplacaklemDropDown.Value, 'Görüntü Kırpma')
                    app.StartXEditField.Visible    = 'on';
                    app.BalangSatrLabel.Visible    = 'on';
                    app.StartYEditField.Visible    = 'on';
                    app.BalangSutnuLabel.Visible           = 'on';
                    app.EndXEditField.Visible      = 'on';
                    app.BitiSatrLabel.Visible              = 'on';
                    app.EndYEditField.Visible      = 'on';
                    app.BitiSutnuLabel.Visible             = 'on';
               else
                    app.StartXEditField.Visible       = 'off';
                    app.BalangSatrLabel.Visible       = 'off';
                    app.StartYEditField.Visible       = 'off';
                    app.BalangSutnuLabel.Visible      = 'off';
                    app.EndXEditField.Visible         = 'off';
                    app.BitiSatrLabel.Visible                 = 'off';
                    app.EndYEditField.Visible         = 'off';
                    app.BitiSutnuLabel.Visible                = 'off';
              end
            end

            if strcmp(app.YaplacaklemDropDown.Value, 'Görüntü Yaklaştırma/Uzaklaştırma')
                    app.ScaleFactorEditField.Visible      = 'on';
                    app.lekFaktrLabel.Visible = 'on';
            else
                    app.ScaleFactorEditField.Visible      = 'off';
                    app.lekFaktrLabel.Visible = 'off';
            end

            if strcmp(app.YaplacaklemDropDown.Value, 'Renk Uzayı Dönüşümleri')
                    app.RenkUzayDropDownLabel.Visible   = 'on';
                    app.RenkUzayiDropDown.Visible = 'on';
            else
                    app.RenkUzayDropDownLabel.Visible    = 'off';
                    app.RenkUzayiDropDown.Visible = 'off';
            end

            if strcmp(app.YaplacaklemDropDown.Value, 'Parlaklık Arttırma')
                    app.ParlaklkLabel.Visible  = 'on';
                    app.ParlaklikSlider.Visible = 'on';
            else
                    app.ParlaklkLabel.Visible  = 'off';
                    app.ParlaklikSlider.Visible = 'off';
            end
            
        end

        % Button pushed function: GrntYkleButton
        function GrntYkleButtonPushed(app, event)
            [file, path] = uigetfile({'*.jpg;*.png;*.bmp'}, 'Image Files');
            if isequal(file, 0)
                return;
            end
            img = imread(fullfile(path, file));
            
            % Görüntüyü sakla
            app.LoadedImage = img;
            
            % Yüklenen görüntüyü sol eksene yerleştir
            imshow(img, 'Parent', app.OriginalAxes);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {266, '1x', 266};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.OriginalAxes = uiaxes(app.LeftPanel);
            title(app.OriginalAxes, 'Title')
            xlabel(app.OriginalAxes, 'X')
            ylabel(app.OriginalAxes, 'Y')
            zlabel(app.OriginalAxes, 'Z')
            app.CenterPanel = uipanel(app.GridLayout);
            app.GrntYkleButton = uibutton(app.CenterPanel, 'push');
            app.GrntYkleButton.ButtonPushedFcn = createCallbackFcn(app, @GrntYkleButtonPushed, true);
            app.GrntYkleButton.Text = 'Görüntü Yükle';
            app.Button = uibutton(app.CenterPanel, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Button.Text = 'Uygula';
            app.YaplacaklemDropDownLabel = uilabel(app.CenterPanel);
            app.YaplacaklemDropDownLabel.Text = 'Yapılacak İşlem';
            app.YaplacaklemDropDown = uidropdown(app.CenterPanel);
            app.YaplacaklemDropDown.Items = {'Gri Dönüşüm', 'Binary Dönüşüm', 'Görüntü Döndürme', 'Görüntü Yaklaştırma/Uzaklaştırma', 'Görüntü Kırpma', 'Renk Uzayı Dönüşümleri', 'Giriş görüntüsüne ait histogram ve orjinal görüntü histogramını germe/genişletme', 'Resim ekleme ', 'Resim Çarpma', 'Parlaklık Arttırma', 'Konvolüsyon İşlemi', 'Eşikleme İşlemi', 'Kenar Bulma Algoritması', 'Görüntüye Gürültü Ekleme', 'Görüntü Filtre Uygulama', 'Morfolojik İşlemler'};
            app.YaplacaklemDropDown.ValueChangedFcn = createCallbackFcn(app, @YaplacaklemDropDownValueChanged, true);
            app.YaplacaklemDropDown.Value = 'Gri Dönüşüm';
            app.GrntlemeGUILabel = uilabel(app.CenterPanel);
            app.GrntlemeGUILabel.Text = 'Görüntü İşleme GUI';
            app.KaDereceDndrlecekEditField_2Label = uilabel(app.CenterPanel);
            app.KaDereceDndrlecekEditField_2Label.Visible = 'off';
            app.KaDereceDndrlecekEditField_2Label.Text = 'Kaç Derece Döndürülecek =';
            app.KaDereceDndrlecekEditField = uieditfield(app.CenterPanel, 'numeric');
            app.KaDereceDndrlecekEditField.Visible = 'off';
            app.BalangSatrLabel = uilabel(app.CenterPanel);
            app.BalangSatrLabel.Visible = 'off';
            app.BalangSatrLabel.Text = 'Başlangıç Satırı';
            app.StartXEditField = uieditfield(app.CenterPanel, 'numeric');
            app.StartXEditField.Visible = 'off';
            app.BalangSutnuLabel = uilabel(app.CenterPanel);
            app.BalangSutnuLabel.Visible = 'off';
            app.BalangSutnuLabel.Text = 'Başlangıç Sutünu';
            app.StartYEditField = uieditfield(app.CenterPanel, 'numeric');
            app.StartYEditField.Visible = 'off';
            app.BitiSatrLabel = uilabel(app.CenterPanel);
            app.BitiSatrLabel.Visible = 'off';
            app.BitiSatrLabel.Text = 'Bitiş Satırı';
            app.EndXEditField = uieditfield(app.CenterPanel, 'numeric');
            app.EndXEditField.Visible = 'off';
            app.BitiSutnuLabel = uilabel(app.CenterPanel);
            app.BitiSutnuLabel.Visible = 'off';
            app.BitiSutnuLabel.Text = 'Bitiş Sutünu';
            app.EndYEditField = uieditfield(app.CenterPanel, 'numeric');
            app.EndYEditField.Visible = 'off';
            app.lekFaktrLabel = uilabel(app.CenterPanel);
            app.lekFaktrLabel.Visible = 'off';
            app.lekFaktrLabel.Text = 'Ölçek Faktörü';
            app.ScaleFactorEditField = uieditfield(app.CenterPanel, 'numeric');
            app.ScaleFactorEditField.Visible = 'off';
            app.RenkUzayDropDownLabel = uilabel(app.CenterPanel);
            app.RenkUzayDropDownLabel.Visible = 'off';
            app.RenkUzayDropDownLabel.Text = 'Renk Uzayı';
            app.RenkUzayiDropDown = uidropdown(app.CenterPanel);
            app.RenkUzayiDropDown.Items = {'HSV', 'YCbCr', 'Lab'};
            app.RenkUzayiDropDown.Visible = 'off';
            app.RenkUzayiDropDown.Value = 'HSV';
            app.ParlaklkLabel = uilabel(app.CenterPanel);
            app.ParlaklkLabel.Visible = 'off';
            app.ParlaklkLabel.Text = 'Parlaklık';
            app.ParlaklikSlider = uislider(app.CenterPanel);
            app.ParlaklikSlider.Limits = [-100 100];
            app.ParlaklikSlider.Visible = 'off';
            app.RightPanel = uipanel(app.GridLayout);
            app.ProcessedAxes = uiaxes(app.RightPanel);
            title(app.ProcessedAxes, 'Title')
            xlabel(app.ProcessedAxes, 'X')
            ylabel(app.ProcessedAxes, 'Y')
            zlabel(app.ProcessedAxes, 'Z')
            app.ProcessedAxes.Visible = 'off';
            app.ProcessedImage = uiimage(app.RightPanel);
            app.ProcessedImage.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GoruntuIslemeOdev

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end