classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        OrijinalGrntLabel           matlab.ui.control.Label
        OriginalImage               matlab.ui.control.Image
        OriginalAxes                matlab.ui.control.UIAxes
        CenterPanel                 matlab.ui.container.Panel
        FiltreBoyutuEditField_2     matlab.ui.control.NumericEditField
        FiltreBoyutuLabel_2         matlab.ui.control.Label
        FiltreSecinizButtonGroup_2  matlab.ui.container.ButtonGroup
        Median_2                    matlab.ui.control.RadioButton
        Mean_2                      matlab.ui.control.RadioButton
        GurultuOraniEditField2      matlab.ui.control.NumericEditField
        GrltOranLabel_2             matlab.ui.control.Label
        SigmaEditField              matlab.ui.control.NumericEditField
        SigmaDeeriLabel             matlab.ui.control.Label
        MorfoIslemButtonGroup       matlab.ui.container.ButtonGroup
        KapamaButton                matlab.ui.control.RadioButton
        AmaButton                   matlab.ui.control.RadioButton
        AnmaButton_2                matlab.ui.control.RadioButton
        GenilemeButton              matlab.ui.control.RadioButton
        FiltreBoyutuEditField       matlab.ui.control.NumericEditField
        FiltreBoyutuLabel           matlab.ui.control.Label
        FiltreSecinizButtonGroup    matlab.ui.container.ButtonGroup
        Median                      matlab.ui.control.RadioButton
        Mean                        matlab.ui.control.RadioButton
        GurultuOraniEditField       matlab.ui.control.NumericEditField
        GrltOranLabel               matlab.ui.control.Label
        BulanikYaricapEditField     matlab.ui.control.NumericEditField
        BulanikYaricapLabel         matlab.ui.control.Label
        RenkUzayiDropDown           matlab.ui.control.DropDown
        RenkUzayDropDownLabel       matlab.ui.control.Label
        ParlaklikSlider             matlab.ui.control.Slider
        ParlaklkLabel               matlab.ui.control.Label
        HistUstEditField            matlab.ui.control.NumericEditField
        stDeerLabel                 matlab.ui.control.Label
        HistAltEditField            matlab.ui.control.NumericEditField
        AltDeerLabel                matlab.ui.control.Label
        ThresholdEditFieldLabel     matlab.ui.control.Label
        ThresholdEditField          matlab.ui.control.NumericEditField
        ScaleFactorEditField        matlab.ui.control.NumericEditField
        lekFaktrLabel               matlab.ui.control.Label
        EndYEditField               matlab.ui.control.NumericEditField
        BitiSutnuLabel              matlab.ui.control.Label
        EndXEditField               matlab.ui.control.NumericEditField
        BitiSatrLabel               matlab.ui.control.Label
        StartYEditField             matlab.ui.control.NumericEditField
        BalangSutnuLabel            matlab.ui.control.Label
        StartXEditField             matlab.ui.control.NumericEditField
        BalangSatrLabel             matlab.ui.control.Label
        KaDereceDndrlecekEditField  matlab.ui.control.NumericEditField
        KaDereceDndrlecekEditField_2Label  matlab.ui.control.Label
        YaplacaklemDropDown         matlab.ui.control.DropDown
        YaplacaklemDropDownLabel    matlab.ui.control.Label
        GrntlemeGUILabel            matlab.ui.control.Label
        Button                      matlab.ui.control.StateButton
        GrntYkleButton              matlab.ui.control.Button
        RightPanel                  matlab.ui.container.Panel
        lenmiGrntLabel              matlab.ui.control.Label
        ProcessedImage              matlab.ui.control.Image
        ProcessedAxes               matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = public)
        LoadedImage % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Button
        function ButtonValueChanged(app, event)
                    app.OriginalImage.Visible  = 'on';
                    app.OriginalImage.ImageSource = app.LoadedImage;
                    selectedProcess = strtrim(app.YaplacaklemDropDown.Value);

            if isempty(app.LoadedImage)
                uialert(app.UIFigure, 'Lütfen önce bir resim yükleyin!', 'Hata');
                return;
            end
            img = app.LoadedImage;

            switch selectedProcess

                case 'Gri Dönüşüm'
                    out = fonkGray(img);
                    showImage(app, out);
                    

                case 'Binary Dönüşüm'
                    
                thr = app.ThresholdEditField.Value;  
                out = fonkBinary(app.LoadedImage, thr);
                app.ProcessedAxes.Visible    = 'off';
                app.ProcessedImage.Visible   = 'on';
                showImage(app, out);

                case 'Görüntü Döndürme'
                derece = app.KaDereceDndrlecekEditField.Value;
                out    = fonkRotate(img, derece);
                app.ProcessedAxes.Visible    = 'off';
                showImage(app, out);

                case 'Görüntü Kırpma'
                sc = round(app.StartXEditField.Value);
                ec = round(app.EndXEditField.Value);
                sr = round(app.StartYEditField.Value);
                er = round(app.EndYEditField.Value);

                imgRect = img;
                red = uint8([255 0 0]);
        
                imgRect(sr, sc:ec,   :) = repmat(reshape(red,1,1,3), [1, ec-sc+1, 1]);
                imgRect(er, sc:ec,   :) = repmat(reshape(red,1,1,3), [1, ec-sc+1, 1]);
           
                imgRect(sr:er, sc,   :) = repmat(reshape(red,1,1,3), [er-sr+1, 1, 1]);
                imgRect(sr:er, ec,   :) = repmat(reshape(red,1,1,3), [er-sr+1, 1, 1]);
            
                showImage(app, imgRect);
                out = fonkCrop(img, sc, sr, ec, er);
                showImage(app, out);

                case'Görüntü Yaklaştırma/Uzaklaştırma'

                s = app.ScaleFactorEditField.Value;
                if isempty(s) || ~isnumeric(s) || s <= 0
                    uialert(app.UIFigure, 'Pozitif bir ölçek faktörü girin.', 'Hata');
                    return;
                end
                zoomed = fonkZoom(app.LoadedImage, s); 
                app.ProcessedAxes.Visible  = 'off';

                app.ProcessedImage.Visible      = 'on';
                app.ProcessedImage.ImageSource  = zoomed;

                [hImg, wImg, ~] = size(zoomed);
                pos = app.ProcessedImage.Position; 
                app.ProcessedImage.Position = [ pos(1), pos(2), wImg, hImg ];


                case 'Renk Uzayı Dönüşümleri'
               
                secimUzayi = app.RenkUzayiDropDown.Value; 
                rgbOut = fonkRenkUzayi(app.LoadedImage, secimUzayi);
    
   
                showImage(app, rgbOut);
                app.ProcessedAxes.Visible      = 'off';
 

                case 'Histogram Germe'

                    app.OriginalImage.Visible     = 'on';
                    app.OriginalImage.ImageSource = app.LoadedImage;
                    
            
                    h1 = fonkHistogramGoster(app.LoadedImage);
                    cla(app.OriginalAxes);
                    bar(app.OriginalAxes, 0:255, h1, 'FaceAlpha',0.5);
                    title(app.OriginalAxes, 'Orijinal Histogram');
                    xlabel(app.OriginalAxes, 'Gri Değer');
                    ylabel(app.OriginalAxes, 'Piksel Sayısı');
                    app.OriginalAxes.Visible = 'on';
                    
                
                    gerilmis = fonkHistogramGerme(app.LoadedImage);
                    
                 
                    rgbOut = repmat(gerilmis, [1 1 3]);
                    showImage(app, rgbOut);          
                    
              
                    h2 = fonkHistogramGoster(gerilmis);
                    cla(app.ProcessedAxes);
                    bar(app.ProcessedAxes, 0:255, h2, 'FaceAlpha',0.5);
                    title(app.ProcessedAxes, 'Gerilmiş Histogram');
                    xlabel(app.ProcessedAxes, 'Gri Değer');
                    ylabel(app.ProcessedAxes, 'Piksel Sayısı');
                    app.ProcessedAxes.Visible = 'on';
                   
                case 'Histogram Genişletme'
                    alt = app.HistAltEditField.Value;
                    ust = app.HistUstEditField.Value;
                
                 
                    app.OriginalImage.Visible     = 'on';
                    app.OriginalImage.ImageSource = app.LoadedImage;
                
                   
                    h1 = fonkHistogramGoster(app.LoadedImage);
                
                   
                    app.OriginalAxes.Color = 'none';          
                    app.OriginalAxes.Visible = 'on';          
                    uistack(app.OriginalAxes,'top');        
           
                
                    cla(app.OriginalAxes);
                    bar(app.OriginalAxes, 0:255, h1, 'FaceAlpha',0.5);
                    title(app.OriginalAxes, 'Orijinal Histogram');
                    xlabel(app.OriginalAxes, 'Gri Değer');
                    ylabel(app.OriginalAxes, 'Piksel Sayısı');
                
              
                    out = fonkHistogramGenislet(app.LoadedImage, alt, ust);
                
                   
                    rgbOut = repmat(out, [1 1 3]);
                    app.ProcessedImage.Visible     = 'on';
                    app.ProcessedImage.ImageSource = rgbOut;
                
          
                    h2 = fonkHistogramGoster(out);
                    cla(app.ProcessedAxes);
                    bar(app.ProcessedAxes, 0:255, h2, 'FaceAlpha',0.5);
                    title(app.ProcessedAxes, 'Genişletilmiş Histogram');
                    xlabel(app.ProcessedAxes, 'Gri Değer');
                    ylabel(app.ProcessedAxes, 'Piksel Sayısı');
                    app.ProcessedAxes.Visible = 'on';
                                       
                case 'Resim ekleme'
                      

                        [file2, path2] = uigetfile({'*.jpg;*.png;*.bmp'}, 'İkinci Resmi Seç');
                        if isequal(file2,0)
                        return;  % kullanıcı iptal etti
                        end
                        img2 = imread(fullfile(path2,file2));

                        out = fonkToplama(app.LoadedImage, img2);

                        app.ProcessedAxes.Visible    = 'off';
                        showImage(app, out);

    
                case 'Resim Çarpma'
                    [file2, path2] = uigetfile({'*.jpg;*.png;*.bmp'}, 'İkinci Resmi Seç');
                    if isequal(file2,0)
                        return;
                    end
                    img2 = imread(fullfile(path2, file2));

                   
                    out = fonkCarpma(app.LoadedImage, img2);

                    
                    app.ProcessedAxes.Visible    = 'off';
                    showImage(app, out);           
   
                case 'Konvolüsyon İşlemi'
                     sigma  = app.SigmaEditField.Value; 
                     radius = round(3*sigma);    
                     outRGB = FonkGaussKonvolusyon(app.LoadedImage, radius, sigma);
                     showImage(app, outRGB);

                     
                case 'Eşikleme İşlemi'
                      gray = fonkGray(app.LoadedImage);
                      bin = FonkAdaptifEsikleme(gray, 15, 5);

                      rgbOut = repmat(bin, [1 1 3]);

                      app.ProcessedAxes.Visible    = 'off';
                      app.ProcessedImage.Visible   = 'on';
                      app.ProcessedImage.ImageSource = rgbOut;
                case 'Kenar Bulma Algoritması'
                    out = fonkSobel(app.LoadedImage);   
                    rgbOut = repmat(out, [1 1 3]);

                    app.ProcessedAxes.Visible    = 'off';
                    showImage(app, rgbOut);

                case 'Görüntüye Gürültü Ekleme'
               
                     oran = app.GurultuOraniEditField2.Value;
                    if isempty(oran) || oran < 0 || oran > 1
                        uialert(app.UIFigure, '0 ile 1 arasında bir gürültü oranı girin.', 'Hata');
                        return;
                    end
                    out = fonkSaltPapper(app.LoadedImage,oran);
                    app.ProcessedAxes.Visible    = 'off';
                    app.ProcessedImage.Visible   = 'on';
                    showImage(app,out);
                    
                   
                case 'Görüntü Filtre Uygulama'
                     
                    secim = app.FiltreSecinizButtonGroup_2.SelectedObject.Text;
                    boyut = round(app.FiltreBoyutuEditField_2.Value);
                    if isempty(boyut) || ~isnumeric(boyut) || mod(boyut,2)==0 || boyut<1
                        uialert(app.UIFigure, 'Lütfen tek sayı filtre boyutu girin (3,5,7...).', 'Hata');
                        return;
                    end

                    if  strcmp(secim, 'Mean')
                        out = fonkMeanFiltre(app.LoadedImage, boyut);
                    else
                        out = fonkMedyanFiltre(app.LoadedImage, boyut);
                    end

                    
                    app.ProcessedAxes.Visible      = 'off';
                    showImage(app, out);

                case 'Gürültü + Filtre Uygulama'

                    oran = app.GurultuOraniEditField.Value;
                    if isempty(oran) || oran < 0 || oran > 1
                        uialert(app.UIFigure, '0 ile 1 arasında bir gürültü oranı girin.', 'Hata');
                        return;
                    end
            
                    noisy = fonkSaltPapper(app.LoadedImage, oran);
           
                    filtBoyut = round(app.FiltreBoyutuEditField.Value);
                    if isempty(filtBoyut) || filtBoyut < 1 || mod(filtBoyut,2)==0
                        uialert(app.UIFigure, 'Tek sayı filtre boyutu girin (3,5,7...).', 'Hata');
                        return;
                    end
            
                    secimFiltre = app.FiltreSecinizButtonGroup.SelectedObject.Text;  
                    if strcmp(secimFiltre, 'Mean')
                        out = fonkMeanFiltre(noisy, filtBoyut);
                    else
                        out = fonkMedyanFiltre(noisy, filtBoyut);
                    end
            
                    showImage(app, out);
                case 'Morfolojik İşlemler'
                    gri    = rgb2gray(app.LoadedImage);
                    ikili  = uint8(gri > 128) * 255;


                    secim = app.MorfoIslemButtonGroup.SelectedObject.Text;
   
                switch secim
                    case 'Genişleme'
                        
                        out = fonkMorfoAsinma(ikili);
                    case 'Aşınma'
                        
                        out = fonkMorfoGenisleme(ikili);
                    case 'Açma'
                        
                        out = fonkMorfoAcma(ikili);
                    case 'Kapama'
                     
                        out = fonkMorfoKapama(ikili);
                    otherwise
                        uialert(app.UIFigure, 'Bilinmeyen morfolojik seçim!', 'Hata');
                        return;
                end

              
                outRGB = repmat(out, [1 1 3]);  
                app.ProcessedAxes.Visible    = 'off';
                showImage(app, outRGB);
                case  'Görüntü Filtre(Blurring)'

                        yaricap = round(app.BulanikYaricapEditField.Value);
                    if isempty(yaricap) || ~isnumeric(yaricap) || yaricap < 1
                        uialert(app.UIFigure, 'Lütfen 1 veya daha büyük bir yarıçap girin.', 'Hata');
                        return;
                    end

                    out = fonkBlurring(app.LoadedImage, yaricap);

                    
                    app.ProcessedAxes.Visible      = 'off';
                    showImage(app, out);
                case 'Parlaklık Arttırma'
                    img = app.LoadedImage;
                    [m,n,c] = size(img);
                    faktor = app.ParlaklikSlider.Value;

                    sonuc = zeros(m,n,c,'uint8');
                    for i = 1:m
                        for j = 1:n
                            for k = 1:c
                                v = double(img(i,j,k)) + faktor;
                                v = min(max(v,0),255);
                                sonuc(i,j,k) = uint8(v);
                            end
                        end
                    end

           
                    app.ProcessedAxes.Visible      = 'off';
                    showImage(app, sonuc);
                otherwise
                    uialert(app.UIFigure, 'Seçilen işlem desteklenmiyor.', 'Hata');
                    return
            
            end
            
        
                function showImage(app, imgOut)
                   
                    [~, ~, nc] = size(imgOut);
                    if nc == 1
                        
                        imgOut = repmat(imgOut, [1 1 3]);
                    end

                    
                    if ~isa(imgOut,'uint8') && ~isa(imgOut,'uint16') ...
                        && ~isa(imgOut,'double') && ~isa(imgOut,'single')
                        imgOut = uint8(imgOut);
                    end

                   
                    app.ProcessedAxes.Visible  = 'off';
                    app.ProcessedImage.Visible = 'on';
                    app.ProcessedImage.ImageSource = imgOut;
                end
        end

        % Value changed function: YaplacaklemDropDown
        function YaplacaklemDropDownValueChanged(app, event)
            if strcmp(app.YaplacaklemDropDown.Value, 'Görüntü Döndürme')
                app.KaDereceDndrlecekEditField.Visible        = 'on';
                app.KaDereceDndrlecekEditField_2Label.Visible = 'on';
            else
                app.KaDereceDndrlecekEditField.Visible        = 'off';
                app.KaDereceDndrlecekEditField_2Label.Visible = 'off';

               if strcmp(app.YaplacaklemDropDown.Value, 'Görüntü Kırpma')
                    app.StartXEditField.Visible    = 'on';
                    app.BalangSatrLabel.Visible    = 'on';
                    app.StartYEditField.Visible    = 'on';
                    app.BalangSutnuLabel.Visible   = 'on';
                    app.EndXEditField.Visible      = 'on';
                    app.BitiSatrLabel.Visible      = 'on';
                    app.EndYEditField.Visible      = 'on';
                    app.BitiSutnuLabel.Visible     = 'on';
               else
                    app.StartXEditField.Visible    = 'off';
                    app.BalangSatrLabel.Visible    = 'off';
                    app.StartYEditField.Visible    = 'off';
                    app.BalangSutnuLabel.Visible   = 'off';
                    app.EndXEditField.Visible      = 'off';
                    app.BitiSatrLabel.Visible      = 'off';
                    app.EndYEditField.Visible      = 'off';
                    app.BitiSutnuLabel.Visible     = 'off';
              end
            end

            if strcmp(app.YaplacaklemDropDown.Value, 'Görüntü Yaklaştırma/Uzaklaştırma')
                    app.ScaleFactorEditField.Visible      = 'on';
                    app.lekFaktrLabel.Visible = 'on';
            else
                    app.ScaleFactorEditField.Visible      = 'off';
                    app.lekFaktrLabel.Visible = 'off';
            end

            if strcmp(app.YaplacaklemDropDown.Value, 'Renk Uzayı Dönüşümleri')
                    app.RenkUzayDropDownLabel.Visible   = 'on';
                    app.RenkUzayiDropDown.Visible = 'on';
            else
                    app.RenkUzayDropDownLabel.Visible    = 'off';
                    app.RenkUzayiDropDown.Visible = 'off';
            end

            if strcmp(app.YaplacaklemDropDown.Value, 'Parlaklık Arttırma')
                    app.ParlaklkLabel.Visible  = 'on';
                    app.ParlaklikSlider.Visible = 'on';
            else
                    app.ParlaklkLabel.Visible  = 'off';
                    app.ParlaklikSlider.Visible = 'off';
            end

            if strcmp(app.YaplacaklemDropDown.Value, 'Görüntüye Gürültü Ekleme')
                    app.GrltOranLabel_2.Visible    = 'on';
                    app.GurultuOraniEditField2.Visible = 'on';
            else
                    app.GrltOranLabel_2.Visible    = 'off';
                    app.GurultuOraniEditField2.Visible = 'off';
            end
            
            if strcmp(app.YaplacaklemDropDown.Value,'Görüntü Filtre Uygulama')
                    app.FiltreSecinizButtonGroup_2.Visible = 'on';
                    app.FiltreBoyutuEditField_2.Visible    = 'on';
                    app.FiltreBoyutuLabel_2.Visible        = 'on';
            else
                    app.FiltreSecinizButtonGroup_2.Visible = 'off';
                    app.FiltreBoyutuEditField_2.Visible    = 'off';
                    app.FiltreBoyutuLabel_2.Visible        = 'off';
            end
            if strcmp(app.YaplacaklemDropDown.Value, 'Görüntü Filtre(Blurring)')
                    app.BulanikYaricapEditField.Visible      = 'on';
                    app.BulanikYaricapLabel.Visible          = 'on';
                    
            else
                    app.BulanikYaricapEditField.Visible  = 'off';
                    app.BulanikYaricapLabel.Visible      = 'off';
            end

            if strcmp(app.YaplacaklemDropDown.Value, 'Morfolojik İşlemler')
                app.MorfoIslemButtonGroup.Visible = 'on';
            else
                app.MorfoIslemButtonGroup.Visible = 'off';
            end
            if strcmp(app.YaplacaklemDropDown.Value, 'Binary Dönüşüm')
                    app.ThresholdEditField.Visible      = 'on';
                    app.ThresholdEditFieldLabel.Visible = 'on';
                    
            else
                    app.ThresholdEditField.Visible      = 'off';
                    app.ThresholdEditFieldLabel.Visible = 'off';
                  
                    
            end

            if strcmp(app.YaplacaklemDropDown.Value, 'Histogram Genişletme')
                app.HistAltEditField.Visible      = 'on';
                app.HistUstEditField.Visible      = 'on';
                app.AltDeerLabel.Visible          = 'on';
                app.stDeerLabel.Visible           = 'on';
            else
                app.HistAltEditField.Visible      = 'off';
                app.HistUstEditField.Visible      = 'off';
                app.AltDeerLabel.Visible          = 'off';
                app.stDeerLabel.Visible           = 'off';
            end

          if strcmp(app.YaplacaklemDropDown.Value, 'Konvolüsyon İşlemi')
              app.SigmaEditField.Visible          = 'on';
              app.SigmaDeeriLabel.Visible         = 'on';
          else
              app.SigmaEditField.Visible          = 'off';
              app.SigmaDeeriLabel.Visible         = 'off';
          end
           if strcmp(app.YaplacaklemDropDown.Value,'Gürültü + Filtre Uygulama')
                    app.FiltreSecinizButtonGroup.Visible = 'on';
                    app.FiltreBoyutuEditField.Visible    = 'on';
                    app.FiltreBoyutuLabel.Visible        = 'on';
                    app.GurultuOraniEditField.Visible    = 'on';
                    app.GrltOranLabel.Visible            = 'on';
            else
                    app.FiltreSecinizButtonGroup.Visible = 'off';
                    app.FiltreBoyutuEditField.Visible    = 'off';
                    app.FiltreBoyutuLabel.Visible        = 'off';
                    app.GurultuOraniEditField.Visible    = 'off';
                    app.GrltOranLabel.Visible            = 'off';
           end

         
        end

        % Button pushed function: GrntYkleButton
        function GrntYkleButtonPushed(app, event)
                [file, path] = uigetfile({'*.jpg;*.png;*.bmp'}, 'Image Files');
                if isequal(file, 0)
                    return;
                end
                % 1) Dosyayı oku
                img = imread(fullfile(path, file));
    
                % 2) Yüklenen görüntüyü sakla
                app.LoadedImage = img;
    
                % 3) Soldaki UIAxes'i gizle, uiimage'ı göster ve resmi ata
                app.OriginalImage.Visible  = 'on';
                app.OriginalImage.ImageSource = img;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {661, 661, 661};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {661, 661};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {382, '1x', 378};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.OriginalAxes = uiaxes(app.LeftPanel);
            title(app.OriginalAxes, 'Title')
            xlabel(app.OriginalAxes, 'X')
            ylabel(app.OriginalAxes, 'Y')
            zlabel(app.OriginalAxes, 'Z')
            app.OriginalAxes.Visible = 'off';
            app.OriginalImage = uiimage(app.LeftPanel);
            app.OriginalImage.Visible = 'off';
            app.OrijinalGrntLabel = uilabel(app.LeftPanel);
            app.OrijinalGrntLabel.Text = 'Orijinal Görüntü';
            app.CenterPanel = uipanel(app.GridLayout);
            app.GrntYkleButton = uibutton(app.CenterPanel, 'push');
            app.GrntYkleButton.ButtonPushedFcn = createCallbackFcn(app, @GrntYkleButtonPushed, true);
            app.GrntYkleButton.Text = 'Görüntü Yükle';
            app.Button = uibutton(app.CenterPanel, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Button.Text = 'Uygula';
            app.GrntlemeGUILabel = uilabel(app.CenterPanel);
            app.GrntlemeGUILabel.Text = 'Görüntü İşleme GUI';
            app.YaplacaklemDropDownLabel = uilabel(app.CenterPanel);
            app.YaplacaklemDropDownLabel.Text = 'Yapılacak İşlem';
            app.YaplacaklemDropDown = uidropdown(app.CenterPanel);
            app.YaplacaklemDropDown.Items = {'Gri Dönüşüm', 'Binary Dönüşüm', 'Görüntü Döndürme', 'Görüntü Yaklaştırma/Uzaklaştırma', 'Görüntü Kırpma', 'Renk Uzayı Dönüşümleri', 'Histogram Germe', 'Histogram Genişletme', 'Resim ekleme ', 'Resim Çarpma', 'Parlaklık Arttırma', 'Konvolüsyon İşlemi', 'Eşikleme İşlemi', 'Kenar Bulma Algoritması', 'Görüntüye Gürültü Ekleme', 'Görüntü Filtre Uygulama', 'Gürültü + Filtre Uygulama', 'Görüntü Filtre(Blurring)', 'Morfolojik İşlemler'};
            app.YaplacaklemDropDown.ValueChangedFcn = createCallbackFcn(app, @YaplacaklemDropDownValueChanged, true);
            app.YaplacaklemDropDown.Value = 'Gri Dönüşüm';
            app.KaDereceDndrlecekEditField_2Label = uilabel(app.CenterPanel);
            app.KaDereceDndrlecekEditField_2Label.Visible = 'off';
            app.KaDereceDndrlecekEditField_2Label.Text = 'Kaç Derece Döndürülecek =';
            app.KaDereceDndrlecekEditField = uieditfield(app.CenterPanel, 'numeric');
            app.KaDereceDndrlecekEditField.Visible = 'off';
            app.BalangSatrLabel = uilabel(app.CenterPanel);
            app.BalangSatrLabel.Visible = 'off';
            app.BalangSatrLabel.Text = 'Başlangıç Satırı =';
            app.StartXEditField = uieditfield(app.CenterPanel, 'numeric');
            app.StartXEditField.Visible = 'off';
            app.BalangSutnuLabel = uilabel(app.CenterPanel);
            app.BalangSutnuLabel.Visible = 'off';
            app.BalangSutnuLabel.Text = 'Başlangıç Sutünu =';
            app.StartYEditField = uieditfield(app.CenterPanel, 'numeric');
            app.StartYEditField.Visible = 'off';
            app.BitiSatrLabel = uilabel(app.CenterPanel);
            app.BitiSatrLabel.Visible = 'off';
            app.BitiSatrLabel.Text = 'Bitiş Satırı =';
            app.EndXEditField = uieditfield(app.CenterPanel, 'numeric');
            app.EndXEditField.Visible = 'off';
            app.BitiSutnuLabel = uilabel(app.CenterPanel);
            app.BitiSutnuLabel.Visible = 'off';
            app.BitiSutnuLabel.Text = 'Bitiş Sutünu =';
            app.EndYEditField = uieditfield(app.CenterPanel, 'numeric');
            app.EndYEditField.Visible = 'off';
            app.lekFaktrLabel = uilabel(app.CenterPanel);
            app.lekFaktrLabel.Visible = 'off';
            app.lekFaktrLabel.Text = 'Ölçek Faktörü =';
            app.ScaleFactorEditField = uieditfield(app.CenterPanel, 'numeric');
            app.ScaleFactorEditField.Visible = 'off';
            app.ThresholdEditField = uieditfield(app.CenterPanel, 'numeric');
            app.ThresholdEditField.Visible = 'off';
            app.ThresholdEditFieldLabel = uilabel(app.CenterPanel);
            app.ThresholdEditFieldLabel.Visible = 'off';
            app.ThresholdEditFieldLabel.Text = 'Eşik Değeri  =';
            app.AltDeerLabel = uilabel(app.CenterPanel);
            app.AltDeerLabel.Visible = 'off';
            app.AltDeerLabel.Text = 'Alt Değer = ';
            app.HistAltEditField = uieditfield(app.CenterPanel, 'numeric');
            app.HistAltEditField.Visible = 'off';
            app.stDeerLabel = uilabel(app.CenterPanel);
            app.stDeerLabel.Visible = 'off';
            app.stDeerLabel.Text = 'Üst Değer =';
            app.HistUstEditField = uieditfield(app.CenterPanel, 'numeric');
            app.HistUstEditField.Visible = 'off';
            app.ParlaklkLabel = uilabel(app.CenterPanel);
            app.ParlaklkLabel.Visible = 'off';
            app.ParlaklkLabel.Text = 'Parlaklık';
            app.ParlaklikSlider = uislider(app.CenterPanel);
            app.ParlaklikSlider.Limits = [-100 100];
            app.ParlaklikSlider.Visible = 'off';
            app.RenkUzayDropDownLabel = uilabel(app.CenterPanel);
            app.RenkUzayDropDownLabel.Visible = 'off';
            app.RenkUzayDropDownLabel.Text = 'Renk Uzayı';
            app.RenkUzayiDropDown = uidropdown(app.CenterPanel);
            app.RenkUzayiDropDown.Items = {'HSV', 'YCbCr', 'Lab'};
            app.RenkUzayiDropDown.Visible = 'off';
            app.RenkUzayiDropDown.Value = 'HSV';
            app.BulanikYaricapLabel = uilabel(app.CenterPanel);
            app.BulanikYaricapLabel.Visible = 'off';
            app.BulanikYaricapLabel.Text = 'Bulanıklaştırma Yarıçapı =';
            app.BulanikYaricapEditField = uieditfield(app.CenterPanel, 'numeric');
            app.BulanikYaricapEditField.Visible = 'off';
            app.GrltOranLabel = uilabel(app.CenterPanel);
            app.GrltOranLabel.Visible = 'off';
            app.GrltOranLabel.Text = 'Gürültü Oranı =';
            app.GurultuOraniEditField = uieditfield(app.CenterPanel, 'numeric');
            app.GurultuOraniEditField.Visible = 'off';
            app.FiltreSecinizButtonGroup = uibuttongroup(app.CenterPanel);
            app.FiltreSecinizButtonGroup.Title = 'Filtre Seçiniz';
            app.FiltreSecinizButtonGroup.Visible = 'off';
            app.Mean = uiradiobutton(app.FiltreSecinizButtonGroup);
            app.Mean.Text = 'Mean';
            app.Mean.Value = true;
            app.Median = uiradiobutton(app.FiltreSecinizButtonGroup);
            app.Median.Text = 'Median';
            app.FiltreBoyutuLabel = uilabel(app.CenterPanel);
            app.FiltreBoyutuLabel.Visible = 'off';
            app.FiltreBoyutuLabel.Text = 'Filtre Boyutu =';
            app.FiltreBoyutuEditField = uieditfield(app.CenterPanel, 'numeric');
            app.FiltreBoyutuEditField.Visible = 'off';
            app.MorfoIslemButtonGroup = uibuttongroup(app.CenterPanel);
            app.MorfoIslemButtonGroup.Title = 'Morfolojik İşlemler';
            app.MorfoIslemButtonGroup.Visible = 'off';
            app.GenilemeButton = uiradiobutton(app.MorfoIslemButtonGroup);
            app.GenilemeButton.Text = 'Genişleme';
            app.GenilemeButton.Value = true;
            app.AnmaButton_2 = uiradiobutton(app.MorfoIslemButtonGroup);
            app.AnmaButton_2.Text = 'Aşınma';
            app.AmaButton = uiradiobutton(app.MorfoIslemButtonGroup);
            app.AmaButton.Text = 'Açma';
            app.KapamaButton = uiradiobutton(app.MorfoIslemButtonGroup);
            app.KapamaButton.Text = 'Kapama';
            app.SigmaDeeriLabel = uilabel(app.CenterPanel);
            app.SigmaDeeriLabel.Visible = 'off';
            app.SigmaDeeriLabel.Text = 'Sigma Değeri = ';
            app.SigmaEditField = uieditfield(app.CenterPanel, 'numeric');
            app.SigmaEditField.Visible = 'off';
            app.GrltOranLabel_2 = uilabel(app.CenterPanel);
            app.GrltOranLabel_2.Visible = 'off';
            app.GrltOranLabel_2.Text = 'Gürültü Oranı = ';
            app.GurultuOraniEditField2 = uieditfield(app.CenterPanel, 'numeric');
            app.GurultuOraniEditField2.Visible = 'off';
            app.FiltreSecinizButtonGroup_2 = uibuttongroup(app.CenterPanel);
            app.FiltreSecinizButtonGroup_2.Title = 'Filtre Seçiniz';
            app.FiltreSecinizButtonGroup_2.Visible = 'off';
            app.Mean_2 = uiradiobutton(app.FiltreSecinizButtonGroup_2);
            app.Mean_2.Text = 'Mean';
            app.Mean_2.Value = true;
            app.Median_2 = uiradiobutton(app.FiltreSecinizButtonGroup_2);
            app.Median_2.Text = 'Median';
            app.FiltreBoyutuLabel_2 = uilabel(app.CenterPanel);
            app.FiltreBoyutuLabel_2.Visible = 'off';
            app.FiltreBoyutuLabel_2.Text = 'Filtre Boyutu = ';
            app.FiltreBoyutuEditField_2 = uieditfield(app.CenterPanel, 'numeric');
            app.FiltreBoyutuEditField_2.Visible = 'off';
            app.RightPanel = uipanel(app.GridLayout);
            app.ProcessedAxes = uiaxes(app.RightPanel);
            title(app.ProcessedAxes, 'Title')
            xlabel(app.ProcessedAxes, 'X')
            ylabel(app.ProcessedAxes, 'Y')
            zlabel(app.ProcessedAxes, 'Z')
            app.ProcessedAxes.Visible = 'off';
            app.ProcessedImage = uiimage(app.RightPanel);
            app.ProcessedImage.Visible = 'off';
            app.lenmiGrntLabel = uilabel(app.RightPanel);
            app.lenmiGrntLabel.Text = 'İşlenmiş Görüntü';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end